classdef IMU_BLE_Test_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        BLETab                          matlab.ui.container.Tab
        bleTable                        matlab.ui.control.Table
        ScanButton                      matlab.ui.control.Button
        ReadingTab                      matlab.ui.container.Tab
        PrecisionSpinnerLabel           matlab.ui.control.Label
        PrecisionSpinner                matlab.ui.control.Spinner
        FrequencyHzSpinnerLabel         matlab.ui.control.Label
        FrequencyHzSpinner              matlab.ui.control.Spinner
        SensorsTab                      matlab.ui.container.Tab
        MagnetometerButton              matlab.ui.control.StateButton
        GyrometerButton                 matlab.ui.control.StateButton
        AccelerometerButton             matlab.ui.control.StateButton
        ConnectionStatusLabel           matlab.ui.control.Label
        ConnectionStatusLamp            matlab.ui.control.Lamp
        SettingsLabel                   matlab.ui.control.Label
        DataRecordingSwitchLabel        matlab.ui.control.Label
        DataRecordingSwitch             matlab.ui.control.Switch
        ConnectButton                   matlab.ui.control.StateButton
        DataRecordingLamp               matlab.ui.control.Lamp
        CountdownLabel                  matlab.ui.control.Label
        DataRecordingTimerSecondsLabel  matlab.ui.control.Label
        DataRecordingTimerSlider        matlab.ui.control.Slider
        RightPanel                      matlab.ui.container.Panel
        UITable                         matlab.ui.control.Table
        FileNameEditFieldLabel          matlab.ui.control.Label
        FileNameEditField               matlab.ui.control.EditField
        SaveFileButton                  matlab.ui.control.Button
        NumberofRowsLabel               matlab.ui.control.Label
        NumberofColumnsLabel            matlab.ui.control.Label
        MovementRankingDropDownLabel    matlab.ui.control.Label
        MovementRankingDropDown         matlab.ui.control.DropDown
        ErrorLabel                      matlab.ui.control.Label
        SetRankButton                   matlab.ui.control.Button
        PerformedMotionDropDownLabel    matlab.ui.control.Label
        PerformedMotionDropDown         matlab.ui.control.DropDown
        SetMotionButton                 matlab.ui.control.Button
        OverwriteFileButton             matlab.ui.control.Button
        ArmDropDownLabel                matlab.ui.control.Label
        ArmDropDown                     matlab.ui.control.DropDown
        DominantHandCheckBox            matlab.ui.control.CheckBox
        CurrentlythischeckboxdoesnothingLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        device
        startImuChar % Description
        imuPrecisionChar
        imuFrequencyChar
        imuStrChar
        
        accelEnabledChar
        gyroEnabledChar
        magEnabledChar
        
    end
    
    methods (Access = private)
        
        function enableButtons(app, TF)
            
            app.DataRecordingSwitch.Enable = TF;
            app.DataRecordingSwitchLabel.Enable = TF;
            app.DataRecordingTimerSlider.Enable = TF;
            app.DataRecordingTimerSecondsLabel.Enable = TF;
            app.DataRecordingLamp.Enable = TF;
            app.FrequencyHzSpinner.Enable = TF;
            app.FrequencyHzSpinnerLabel.Enable = TF;
            app.PrecisionSpinner.Enable = TF;
            app.PrecisionSpinnerLabel.Enable = TF;
            app.AccelerometerButton.Enable = TF;
            app.GyrometerButton.Enable = TF;
            app.MagnetometerButton.Enable = TF;
            
        end
        
        function updateTable(app)
            global data_table
            app.UITable.Data = data_table;
            app.UITable.ColumnName = data_table.Properties.VariableNames; 
            app.NumberofRowsLabel.Text = "Number of Rows = " + string(height(data_table));
            app.NumberofColumnsLabel.Text = "Number of Columns = " + string(width(data_table));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
           global data_table
           data_table = table();
            
           AccelerometerButtonValueChanged(app);
           GyrometerButtonValueChanged(app);
           MagnetometerButtonValueChanged(app);

           app.ErrorLabel.Text = "";
           app.ConnectionStatusLabel.Text = "Disconnected";
           app.CountdownLabel.Text = "";
           
           %app.DataRecordingTimerSlider.
           
           ble_list = blelist;
           app.bleTable.Data = ble_list(:,2:4);
           app.bleTable.ColumnName = ble_list.Properties.VariableNames(2:4);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {450, 450};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {272, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: PrecisionSpinner
        function PrecisionSpinnerValueChanged(app, event)
            if isprop(app, 'device') && ~isempty(app.device) && isprop(app, 'imuPrecisionChar') && ~isempty(app.imuPrecisionChar)
                write(app.imuPrecisionChar,app.PrecisionSpinner.Value);
            end
        end

        % Value changed function: FrequencyHzSpinner
        function FrequencyHzSpinnerValueChanged(app, event)
            if isprop(app, 'device') && ~isempty(app.device) && isprop(app, 'imuFrequencyChar') && ~isempty(app.imuFrequencyChar)
                write(app.imuFrequencyChar,app.FrequencyHzSpinner.Value);
            end
        end

        % Value changed function: DataRecordingSwitch
        function DataRecordingSwitchValueChanged(app, event)
            global data_table
            
            app.DataRecordingSwitch.Enable = 0;
            app.DataRecordingTimerSlider.Enable = 0;
            
            if app.DataRecordingSwitch.Value
                if isprop(app, 'startImuChar') && ~isempty(app.startImuChar)
                    
                    data_table = table();
                    app.UITable.Data = table();
                    app.NumberofRowsLabel.Text = "Number of Rows = N/A";
                    app.NumberofColumnsLabel.Text = "Number of Columns = N/A";
                    
                    %Tells arduino to start collecting Data
                    app.DataRecordingLamp.Color = [1,0.5,0];
                    
                    app.CountdownLabel.Text = "3";
                    pause(0.5);
                    app.DataRecordingLamp.Enable = 0;
                    pause(0.5);
                    app.CountdownLabel.Text = "2";
                    app.DataRecordingLamp.Enable = 1;
                    pause(0.5);
                    app.DataRecordingLamp.Enable = 0;
                    pause(0.5);
                    app.CountdownLabel.Text = "1";
                    app.DataRecordingLamp.Enable = 1;
                    pause(0.5);
                    app.DataRecordingLamp.Enable = 0;
                    pause(0.5);
                    app.CountdownLabel.Text = "Go";
                    app.DataRecordingLamp.Color = [0,1,0];
                    app.DataRecordingLamp.Enable = 1;
                    pause(1);
                    app.CountdownLabel.Text = "";
                    
                    write(app.startImuChar,1);
                    
                    if app.DataRecordingTimerSlider.Value ~= 0
                        app.DataRecordingSwitch.Enable=0;
                        
                        pause(app.DataRecordingTimerSlider.Value)
                        
                        write(app.startImuChar,0);
                        app.DataRecordingLamp.Color = [1,0,0];
                        app.DataRecordingLamp.Enable = 1;               
                        
                        app.updateTable();
                        
                        app.DataRecordingSwitch.Value=0;
                        app.DataRecordingSwitch.Enable=1;
                        app.DataRecordingTimerSlider.Enable = 1;
                    else
                        app.DataRecordingSwitch.Enable=1;
                    end
                end
            else
                write(app.startImuChar,0);
                app.DataRecordingLamp.Color = [1,0,0];
                app.DataRecordingLamp.Enable = 1;
                
                app.updateTable();
                
                app.DataRecordingSwitch.Enable = 1;
                app.DataRecordingTimerSlider.Enable = 1;
            end

            if ~isempty(app.FileNameEditField.Value) && ~isempty(app.UITable.Data)
                app.SaveFileButton.Enable=1;
                app.OverwriteFileButton.Enable=1;
            else
                app.SaveFileButton.Enable=0;
                app.OverwriteFileButton.Enable=0;
            end
        end

        % Value changed function: ConnectButton
        function ConnecttoDeviceButtonValueChanged(app, event)
            global accel_enabled gyro_enabled mag_enabled
            
            value = app.ConnectButton.Value;
            app.ConnectButton.Enable = 0;
            
            app.ConnectionStatusLamp.Enable = 0;
            app.ConnectionStatusLamp.Color = [255/255,165/255,0];
            
            if value == 1
                
                app.ConnectionStatusLabel.Text = "Connecting...";
                
                if isprop(app, 'device') && isempty(app.device)
                   try
                       app.device = ble('LeedsMechEngWearable');   % If this doesnt work call 'blelist' in the command window to discover devices
                   catch
                       app.ConnectionStatusLabel.Text = "Couldn't Connect...";
                       app.ConnectionStatusLamp.Color = [1,0,0];
                       app.ConnectionStatusLamp.Enable = 1;
                       app.ConnectButton.Value = 0;
                       app.ConnectButton.Enable = 1;
                       %error('Could not connect...');
                       return
                   end
                
                   app.startImuChar = characteristic(app.device, "C3D0", "49E8");
                   app.imuPrecisionChar = characteristic(app.device, "C3D0", "98B2");
                   app.imuFrequencyChar = characteristic(app.device, "C3D0", "AE89");
                   app.imuStrChar = characteristic(app.device, "C3D0", "785F");
                    
                   app.accelEnabledChar = characteristic(app.device, "C3D0", "1A55");
                   app.gyroEnabledChar = characteristic(app.device, "C3D0", "3901");
                   app.magEnabledChar = characteristic(app.device, "C3D0", "552B");
                    
                   write(app.startImuChar, 0);
                    
                   % This line calls a function 'displayCharacteristicData' each time the
                   %   imuStrChar characteristic is updated with a new value
                   app.imuStrChar.DataAvailableFcn = @ArduinoStr2Table;
                end     
                
                write(app.imuPrecisionChar,app.PrecisionSpinner.Value);
                write(app.imuFrequencyChar,app.FrequencyHzSpinner.Value);
                write(app.accelEnabledChar,accel_enabled);
                write(app.gyroEnabledChar,gyro_enabled);
                write(app.magEnabledChar,mag_enabled);
                
                app.ConnectionStatusLamp.Color = [0,0,1];
                app.ConnectionStatusLabel.Text = "Connected";
                
                app.ConnectButton.Enable = 1;
                app.ConnectButton.Text = "Disconnect";
                
                %Enable all BLE buttons
                app.enableButtons(1);
                
            else
                app.ConnectionStatusLabel.Text = "Disconnecting...";
                
                if isprop(app, 'startImuChar') && ~isempty(app.startImuChar)
                    write(app.startImuChar,0); %To ensure data recording is turned off
                end
                
                clear device
                app.device = [];
                app.ConnectionStatusLabel.Text = "Disconnected";
                app.ConnectionStatusLamp.Color = [1,0,0];
                
                app.ConnectButton.Enable = 1;
                app.ConnectButton.Text = "Connect";
                
                %Disable all BLE buttons
                app.enableButtons(0);
                app.DataRecordingLamp.Color = [1,0,0];

            end
            
            app.ConnectionStatusLamp.Enable = 1;
            
        end

        % Value changed function: AccelerometerButton
        function AccelerometerButtonValueChanged(app, event)
            global accel_enabled
            
            if app.AccelerometerButton.Value
               accel_enabled = 0;
            else
               accel_enabled = 1;
            end
            
            if isprop(app, 'accelEnabledChar') && ~isempty(app.accelEnabledChar)
                write(app.accelEnabledChar,accel_enabled);
            end
        end

        % Value changed function: GyrometerButton
        function GyrometerButtonValueChanged(app, event)
            global gyro_enabled
            
            if app.GyrometerButton.Value
               gyro_enabled = 0;
            else
               gyro_enabled = 1;
            end
            
            if isprop(app, 'device') && ~isempty(app.device) && isprop(app, 'gyroEnabledChar') && ~isempty(app.gyroEnabledChar)
                write(app.gyroEnabledChar,gyro_enabled);
            end
        end

        % Value changed function: MagnetometerButton
        function MagnetometerButtonValueChanged(app, event)
            global mag_enabled
            
            if app.MagnetometerButton.Value
               mag_enabled = 0;
            else
               mag_enabled = 1;
            end
            
            if isprop(app, 'device') && ~isempty(app.device) && isprop(app, 'magEnabledChar') && ~isempty(app.magEnabledChar)
                write(app.magEnabledChar,mag_enabled);
            end
            
        end

        % Button pushed function: OverwriteFileButton, 
        % SaveFileButton
        function SaveFileButtonPushed(app, event)
            app.ErrorLabel.Text = "";
            app.ErrorLabel.FontColor = [1,0,0];
            
            global data_table
            saved_table = data_table;
            
            if isempty(data_table)
                app.ErrorLabel.Text = "There is no recorded data to be saved...";
                return
            end
            
            if sum(ismember(data_table.Properties.VariableNames,'movement_type')) == 0
                app.ErrorLabel.Text = "Performed motion was not set for the dataset...";
                return
            end
            
            if sum(ismember(data_table.Properties.VariableNames,'movement_rank')) == 0
                app.ErrorLabel.Text = "Movement rank was not set for the dataset...";
                return
            end

            folder_name = "Matlab App Data Tables";
            sub_folder_name = string(app.ArmDropDown.Value) + " Arm";
            sub_sub_folder_name = string(app.PerformedMotionDropDown.Value);
            file_name = string(app.FileNameEditField.Value);
            
            % Check to see if folder "Matlab App Data Tables" exists
            if not(exist(folder_name,'dir'))
                mkdir(folder_name)
            end
            
            % Check to see if folder "Matlab App Data Tables/Right Arm" exists
            if not(exist(folder_name+"/"+sub_folder_name, 'dir'))
                mkdir(folder_name+"/"+sub_folder_name)
            end
            
            % Check to see if folder "Matlab App Data Tables/Right Arm/Motion 2" exists
            if not(exist(folder_name+"/"+sub_folder_name+"/"+sub_sub_folder_name, 'dir'))
                mkdir(folder_name+"/"+sub_folder_name+"/"+sub_sub_folder_name)
            end
            
            % Check to see if file allready exists within the folder
            if exist(folder_name+"/"+sub_folder_name+"/"+sub_sub_folder_name+"/"+file_name+".mat","file")
                if event.Source.Text == "Save File"
                    app.ErrorLabel.Text = "File with this name already exists at location " + folder_name+"/"+sub_folder_name+"/"+sub_sub_folder_name;
                    return
                end
            end
            
            % An error catching system. Checks to see if the file could be
            % saved!
            try
                save(folder_name+"/"+sub_folder_name+"/"+sub_sub_folder_name+"/"+file_name,'saved_table'); 
            catch
                app.ErrorLabel.FontColor = [1,0,0];
                app.ErrorLabel.Text = "Could not save the table...";
                return
            end
            app.ErrorLabel.FontColor = [0.39,0.83,0.07]; 
            if event.Source.Text == "Save File"
                app.ErrorLabel.Text = "Successfully Saved!";
            elseif event.Source.Text == "Overwrite File"
                app.ErrorLabel.Text = "Successfully Overwritten!";
            end
            
            pause(1.5);
            app.ErrorLabel.Text = "";

        end

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            ble_list = blelist;
            app.bleTable.Data = ble_list(:,2:4);
            app.bleTable.ColumnName = ble_list.Properties.VariableNames(2:4);
        end

        % Callback function: PerformedMotionDropDown, SetMotionButton
        function SetMotionButtonPushed(app, event)
            global data_table
            movement_column_title = 'movement_type';
            
            output = split(app.PerformedMotionDropDown.Value);
            rank = str2double(output(2));
            data_table{:,movement_column_title} = rank;
            
            app.UITable.Data = data_table;
            app.UITable.ColumnName = data_table.Properties.VariableNames; 
            app.NumberofRowsLabel.Text = "Number of Rows = " + string(height(data_table));
            app.NumberofColumnsLabel.Text = "Number of Columns = " + string(width(data_table));
        end

        % Callback function: MovementRankingDropDown, SetRankButton
        function SetRankButtonPushed(app, event)
            global data_table
            ranking_column_title = 'movement_rank';
            
            output = split(app.MovementRankingDropDown.Value, "/");
            rank = str2double(output(1))/str2double(output(2));
            data_table{:,ranking_column_title} = rank;
            
            app.UITable.Data = data_table;
            app.UITable.ColumnName = data_table.Properties.VariableNames; 
            app.NumberofRowsLabel.Text = "Number of Rows = " + string(height(data_table));
            app.NumberofColumnsLabel.Text = "Number of Columns = " + string(width(data_table));
        end

        % Value changing function: FileNameEditField
        function FileNameEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
            if ~isempty(changingValue) && ~isempty(app.UITable.Data)
                app.SaveFileButton.Enable=1;
                app.OverwriteFileButton.Enable=1;
            else
                app.SaveFileButton.Enable=0;
                app.OverwriteFileButton.Enable=0;
            end
        end

        % Value changed function: DataRecordingTimerSlider
        function DataRecordingTimerSliderValueChanged(app, event)
            app.DataRecordingTimerSlider.Value = round(app.DataRecordingTimerSlider.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.BLETab = uitab(app.TabGroup);
            app.BLETab.Title = 'BLE';
            app.bleTable = uitable(app.BLETab);
            app.bleTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.bleTable.RowName = {};
            app.ScanButton = uibutton(app.BLETab, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.ReadingTab = uitab(app.TabGroup);
            app.ReadingTab.Title = 'Reading';
            app.PrecisionSpinnerLabel = uilabel(app.ReadingTab);
            app.PrecisionSpinnerLabel.Enable = 'off';
            app.PrecisionSpinnerLabel.Text = 'Precision';
            app.PrecisionSpinner = uispinner(app.ReadingTab);
            app.PrecisionSpinner.Limits = [0 6];
            app.PrecisionSpinner.ValueChangedFcn = createCallbackFcn(app, @PrecisionSpinnerValueChanged, true);
            app.PrecisionSpinner.Enable = 'off';
            app.PrecisionSpinner.Value = 3;
            app.FrequencyHzSpinnerLabel = uilabel(app.ReadingTab);
            app.FrequencyHzSpinnerLabel.Enable = 'off';
            app.FrequencyHzSpinnerLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzSpinner = uispinner(app.ReadingTab);
            app.FrequencyHzSpinner.Limits = [1 60];
            app.FrequencyHzSpinner.ValueChangedFcn = createCallbackFcn(app, @FrequencyHzSpinnerValueChanged, true);
            app.FrequencyHzSpinner.Enable = 'off';
            app.FrequencyHzSpinner.Value = 20;
            app.SensorsTab = uitab(app.TabGroup);
            app.SensorsTab.Title = 'Sensors';
            app.MagnetometerButton = uibutton(app.SensorsTab, 'state');
            app.MagnetometerButton.ValueChangedFcn = createCallbackFcn(app, @MagnetometerButtonValueChanged, true);
            app.MagnetometerButton.Enable = 'off';
            app.MagnetometerButton.Text = 'Magnetometer';
            app.GyrometerButton = uibutton(app.SensorsTab, 'state');
            app.GyrometerButton.ValueChangedFcn = createCallbackFcn(app, @GyrometerButtonValueChanged, true);
            app.GyrometerButton.Enable = 'off';
            app.GyrometerButton.Text = 'Gyrometer';
            app.AccelerometerButton = uibutton(app.SensorsTab, 'state');
            app.AccelerometerButton.ValueChangedFcn = createCallbackFcn(app, @AccelerometerButtonValueChanged, true);
            app.AccelerometerButton.Enable = 'off';
            app.AccelerometerButton.Text = 'Accelerometer';
            app.ConnectionStatusLabel = uilabel(app.LeftPanel);
            app.ConnectionStatusLabel.Text = 'ConnectionStatus';
            app.ConnectionStatusLamp = uilamp(app.LeftPanel);
            app.SettingsLabel = uilabel(app.LeftPanel);
            app.SettingsLabel.Text = 'Settings';
            app.DataRecordingSwitchLabel = uilabel(app.LeftPanel);
            app.DataRecordingSwitchLabel.Enable = 'off';
            app.DataRecordingSwitchLabel.Text = 'Data Recording';
            app.DataRecordingSwitch = uiswitch(app.LeftPanel, 'slider');
            app.DataRecordingSwitch.ItemsData = [0 1];
            app.DataRecordingSwitch.ValueChangedFcn = createCallbackFcn(app, @DataRecordingSwitchValueChanged, true);
            app.DataRecordingSwitch.Enable = 'off';
            app.DataRecordingSwitch.Value = 0;
            app.ConnectButton = uibutton(app.LeftPanel, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnecttoDeviceButtonValueChanged, true);
            app.ConnectButton.Text = 'Connect';
            app.DataRecordingLamp = uilamp(app.LeftPanel);
            app.DataRecordingLamp.Enable = 'off';
            app.CountdownLabel = uilabel(app.LeftPanel);
            app.CountdownLabel.Text = 'Countdown';
            app.DataRecordingTimerSecondsLabel = uilabel(app.LeftPanel);
            app.DataRecordingTimerSecondsLabel.Enable = 'off';
            app.DataRecordingTimerSecondsLabel.Text = 'Data Recording Timer (Seconds)';
            app.DataRecordingTimerSlider = uislider(app.LeftPanel);
            app.DataRecordingTimerSlider.Limits = [0 10];
            app.DataRecordingTimerSlider.MajorTicks = [0 2 4 6 8 10];
            app.DataRecordingTimerSlider.MajorTickLabels = {'Off', '2', '4', '6', '8', '10'};
            app.DataRecordingTimerSlider.ValueChangedFcn = createCallbackFcn(app, @DataRecordingTimerSliderValueChanged, true);
            app.DataRecordingTimerSlider.MinorTicks = [1 3 5 7 9];
            app.DataRecordingTimerSlider.Enable = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.FileNameEditFieldLabel = uilabel(app.RightPanel);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.RightPanel, 'text');
            app.FileNameEditField.ValueChangingFcn = createCallbackFcn(app, @FileNameEditFieldValueChanging, true);
            app.SaveFileButton = uibutton(app.RightPanel, 'push');
            app.SaveFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFileButtonPushed, true);
            app.SaveFileButton.Enable = 'off';
            app.SaveFileButton.Text = 'Save File';
            app.NumberofRowsLabel = uilabel(app.RightPanel);
            app.NumberofRowsLabel.Text = 'Number of Rows = N/A';
            app.NumberofColumnsLabel = uilabel(app.RightPanel);
            app.NumberofColumnsLabel.Text = 'Number of Columns = N/A';
            app.MovementRankingDropDownLabel = uilabel(app.RightPanel);
            app.MovementRankingDropDownLabel.Text = 'Movement Ranking';
            app.MovementRankingDropDown = uidropdown(app.RightPanel);
            app.MovementRankingDropDown.Items = {'1/5', '2/5', '3/5', '4/5', '5/5'};
            app.MovementRankingDropDown.ValueChangedFcn = createCallbackFcn(app, @SetRankButtonPushed, true);
            app.MovementRankingDropDown.Value = '1/5';
            app.ErrorLabel = uilabel(app.RightPanel);
            app.SetRankButton = uibutton(app.RightPanel, 'push');
            app.SetRankButton.ButtonPushedFcn = createCallbackFcn(app, @SetRankButtonPushed, true);
            app.SetRankButton.Text = 'Set Rank';
            app.PerformedMotionDropDownLabel = uilabel(app.RightPanel);
            app.PerformedMotionDropDownLabel.Text = 'Performed Motion';
            app.PerformedMotionDropDown = uidropdown(app.RightPanel);
            app.PerformedMotionDropDown.Items = {'Motion 1', 'Motion 2', 'Motion 3', 'Motion 4'};
            app.PerformedMotionDropDown.ValueChangedFcn = createCallbackFcn(app, @SetMotionButtonPushed, true);
            app.PerformedMotionDropDown.Value = 'Motion 1';
            app.SetMotionButton = uibutton(app.RightPanel, 'push');
            app.SetMotionButton.ButtonPushedFcn = createCallbackFcn(app, @SetMotionButtonPushed, true);
            app.SetMotionButton.Text = 'Set Motion';
            app.OverwriteFileButton = uibutton(app.RightPanel, 'push');
            app.OverwriteFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFileButtonPushed, true);
            app.OverwriteFileButton.Enable = 'off';
            app.OverwriteFileButton.Text = 'Overwrite File';
            app.ArmDropDownLabel = uilabel(app.RightPanel);
            app.ArmDropDownLabel.Text = 'Arm';
            app.ArmDropDown = uidropdown(app.RightPanel);
            app.ArmDropDown.Items = {'Right', 'Left'};
            app.ArmDropDown.Value = 'Right';
            app.DominantHandCheckBox = uicheckbox(app.RightPanel);
            app.DominantHandCheckBox.Text = 'Is this your dominant hand?';
            app.DominantHandCheckBox.Value = true;
            app.CurrentlythischeckboxdoesnothingLabel = uilabel(app.RightPanel);
            app.CurrentlythischeckboxdoesnothingLabel.Text = '<- Currently this check-box does nothing';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IMU_BLE_Test_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end