classdef ImuBleDataCollectionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        DataRecordingTimerSlider        matlab.ui.control.Slider
        DataRecordingTimerSecondsLabel  matlab.ui.control.Label
        CountdownLabel                  matlab.ui.control.Label
        DataRecordingLamp               matlab.ui.control.Lamp
        ConnectButton                   matlab.ui.control.StateButton
        DataRecordingSwitch             matlab.ui.control.Switch
        DataRecordingSwitchLabel        matlab.ui.control.Label
        SettingsLabel                   matlab.ui.control.Label
        ConnectionStatusLamp            matlab.ui.control.Lamp
        ConnectionStatusLabel           matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        CalibrationTab                  matlab.ui.container.Tab
        CalibrationIMU3GyroZLabel       matlab.ui.control.Label
        CalibrationIMU3GyroYLabel       matlab.ui.control.Label
        CalibrationIMU3GyroXLabel       matlab.ui.control.Label
        CalibrationInfoLabel_10         matlab.ui.control.Label
        CalibrationIMU2GyroZLabel       matlab.ui.control.Label
        CalibrationIMU2GyroYLabel       matlab.ui.control.Label
        CalibrationIMU2GyroXLabel       matlab.ui.control.Label
        CalibrationInfoLabel_6          matlab.ui.control.Label
        CalibrationIMU1GyroZLabel       matlab.ui.control.Label
        CalibrationIMU1GyroYLabel       matlab.ui.control.Label
        CalibrationIMU1GyroXLabel       matlab.ui.control.Label
        CalibrationCountdownLabel       matlab.ui.control.Label
        CalibrationInfoLabel            matlab.ui.control.Label
        WhichmotionwasperformedLabel_3  matlab.ui.control.Label
        CalibrateSensorsButton          matlab.ui.control.Button
        BLETab                          matlab.ui.container.Tab
        ScanButton                      matlab.ui.control.Button
        bleTable                        matlab.ui.control.Table
        ReadingTab                      matlab.ui.container.Tab
        FrequencyHzSpinner              matlab.ui.control.Spinner
        FrequencyHzSpinnerLabel         matlab.ui.control.Label
        PrecisionSpinner                matlab.ui.control.Spinner
        PrecisionSpinnerLabel           matlab.ui.control.Label
        SensorsTab                      matlab.ui.container.Tab
        AccelerometerButton             matlab.ui.control.StateButton
        GyrometerButton                 matlab.ui.control.StateButton
        MagnetometerButton              matlab.ui.control.StateButton
        RightPanel                      matlab.ui.container.Panel
        WhichmotionwasperformedLabel_2  matlab.ui.control.Label
        WhichmotionwasperformedLabel    matlab.ui.control.Label
        HowsmoothwasthemotionperformedLabel  matlab.ui.control.Label
        HowaccuratlywasthemotionperformedLabel  matlab.ui.control.Label
        SetRankButton_2                 matlab.ui.control.Button
        SmoothnessRankDropDown          matlab.ui.control.DropDown
        SmoothnessRankDropDownLabel     matlab.ui.control.Label
        ArmDropDown                     matlab.ui.control.DropDown
        ArmDropDownLabel                matlab.ui.control.Label
        OverwriteFileButton             matlab.ui.control.Button
        SetMotionButton                 matlab.ui.control.Button
        PerformedMotionDropDown         matlab.ui.control.DropDown
        PerformedMotionDropDownLabel    matlab.ui.control.Label
        SetRankButton                   matlab.ui.control.Button
        ErrorLabel                      matlab.ui.control.Label
        CompletenessRankDropDown        matlab.ui.control.DropDown
        CompletenessRankDropDownLabel   matlab.ui.control.Label
        NumberofColumnsLabel            matlab.ui.control.Label
        NumberofRowsLabel               matlab.ui.control.Label
        SaveFileButton                  matlab.ui.control.Button
        FileNameEditField               matlab.ui.control.EditField
        FileNameEditFieldLabel          matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        device
        startImuChar % Description
        imuPrecisionChar
        imuFrequencyChar
        imuStrChar
        
        accelEnabledChar
        gyroEnabledChar
        magEnabledChar
        
        gyro1XOffsetChar
        gyro1YOffsetChar
        gyro1ZOffsetChar
        gyro2XOffsetChar
        gyro2YOffsetChar
        gyro2ZOffsetChar
        gyro3XOffsetChar
        gyro3YOffsetChar
        gyro3ZOffsetChar
        
        sensorsCalibrated = false;
    end
    
    methods (Access = private)
        
        function enableButtons(app, TF)
            
            app.ConnectButton.Enable = TF;
            app.DataRecordingSwitch.Enable = TF;
            app.DataRecordingSwitchLabel.Enable = TF;
            app.DataRecordingTimerSlider.Enable = TF;
            app.DataRecordingTimerSecondsLabel.Enable = TF;
            app.DataRecordingLamp.Enable = TF;
            app.FrequencyHzSpinner.Enable = TF;
            app.FrequencyHzSpinnerLabel.Enable = TF;
            app.PrecisionSpinner.Enable = TF;
            app.PrecisionSpinnerLabel.Enable = TF;
            app.AccelerometerButton.Enable = TF;
            app.GyrometerButton.Enable = TF;
            app.MagnetometerButton.Enable = TF;
            app.CalibrateSensorsButton.Enable = TF;
            
        end
        
        function updateTable(app)
            global data_table
            app.UITable.Data = data_table;
            app.UITable.ColumnName = data_table.Properties.VariableNames; 
            app.NumberofRowsLabel.Text = "Number of Rows = " + string(height(data_table));
            app.NumberofColumnsLabel.Text = "Number of Columns = " + string(width(data_table));
        end
        
        
        function float_value = uint8ArrayToFloat(~, uint8_array)
            float_value = swapbytes(typecast( fliplr(uint8(uint8_array)) , 'single'));
        end
        
        function uint8_array = floatToUint8Array(~, float_value)
            uint8_array = (typecast(single(float_value), 'uint8'));
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
           addpath("BLE Functions");
            
           global data_table
           data_table = table();
            
           AccelerometerButtonValueChanged(app);
           GyrometerButtonValueChanged(app);
           MagnetometerButtonValueChanged(app);

           app.ErrorLabel.Text = "";
           app.ConnectionStatusLabel.Text = "Disconnected";
           app.CountdownLabel.Text = "";
           app.CalibrationCountdownLabel.Text = "";
           
           %app.DataRecordingTimerSlider.
           
           ble_list = blelist;
           app.bleTable.Data = ble_list(:,2:4);
           app.bleTable.ColumnName = ble_list.Properties.VariableNames(2:4);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {450, 450};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {279, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: PrecisionSpinner
        function PrecisionSpinnerValueChanged(app, event)
            if isprop(app, 'device') && ~isempty(app.device) && isprop(app, 'imuPrecisionChar') && ~isempty(app.imuPrecisionChar)
                write(app.imuPrecisionChar,app.PrecisionSpinner.Value,"withresponse");
            end
        end

        % Value changed function: FrequencyHzSpinner
        function FrequencyHzSpinnerValueChanged(app, event)
            if isprop(app, 'device') && ~isempty(app.device) && isprop(app, 'imuFrequencyChar') && ~isempty(app.imuFrequencyChar)
                write(app.imuFrequencyChar,app.FrequencyHzSpinner.Value,"withresponse");
            end
        end

        % Value changed function: DataRecordingSwitch
        function DataRecordingSwitchValueChanged(app, event)
            global data_table
            
            app.enableButtons(false);
            
            if app.DataRecordingSwitch.Value
                if isprop(app, 'startImuChar') && ~isempty(app.startImuChar)
                    
                    data_table = table();
                    app.UITable.Data = table();
                    app.NumberofRowsLabel.Text = "Number of Rows = N/A";
                    app.NumberofColumnsLabel.Text = "Number of Columns = N/A";
                    
                    %Tells arduino to start collecting Data
                    app.DataRecordingLamp.Color = [1,0.5,0];
                    
                    app.CountdownLabel.Text = "3";
                    pause(0.5);
                    app.DataRecordingLamp.Enable = 0;
                    pause(0.5);
                    app.CountdownLabel.Text = "2";
                    app.DataRecordingLamp.Enable = 1;
                    pause(0.5);
                    app.DataRecordingLamp.Enable = 0;
                    pause(0.5);
                    app.CountdownLabel.Text = "1";
                    app.DataRecordingLamp.Enable = 1;
                    pause(0.5);
                    app.DataRecordingLamp.Enable = 0;
                    pause(0.5);
                    app.CountdownLabel.Text = "Go";
                    app.DataRecordingLamp.Color = [0,1,0];
                    app.DataRecordingLamp.Enable = 1;
                    pause(1);
                    app.CountdownLabel.Text = "";
                    
                    write(app.startImuChar,1,"withresponse");
                    
                    if app.DataRecordingTimerSlider.Value ~= 0
                        app.DataRecordingSwitch.Enable=0;
                        
                        %pause(app.DataRecordingTimerSlider.Value)

                        count_from = ceil(app.DataRecordingTimerSlider.Value);
                        
                        %For Loop:
                        %  The for loop runs for each second of recording
                        %  and pauses for 1 second each iteration
                        
                        for timer_val = count_from:-0.1:0
                            app.CountdownLabel.Text = compose("%.1f", timer_val);
                            pause(0.1);
                        end
                        
                        write(app.startImuChar,0,"withresponse");
                        app.CountdownLabel.Text = "Finished...";               
                        app.updateAppLayout();
                        
                        app.updateTable();
                        
                        app.CountdownLabel.Text = "";
                        app.DataRecordingLamp.Color = [1,0,0];
                        app.DataRecordingLamp.Enable = 1;  
                        app.DataRecordingSwitch.Value=0;
                        
                        app.enableButtons(true);
                    else
                        app.DataRecordingSwitchLabel.Enable=1;
                        app.DataRecordingSwitch.Enable=1;
                    end
                end
            else
                write(app.startImuChar,0,"withresponse");
                app.DataRecordingLamp.Color = [1,0,0];
                app.DataRecordingLamp.Enable = 1;
                
                app.updateTable();
                
                app.enableButtons(true);
            end

            if ~isempty(app.FileNameEditField.Value) && ~isempty(app.UITable.Data)
                app.SaveFileButton.Enable=1;
                app.OverwriteFileButton.Enable=1;
            else
                app.SaveFileButton.Enable=0;
                app.OverwriteFileButton.Enable=0;
            end
            
        end

        % Value changed function: ConnectButton
        function ConnecttoDeviceButtonValueChanged(app, event)
            global accel_enabled gyro_enabled mag_enabled
            
            value = app.ConnectButton.Value;
            app.ConnectButton.Enable = 0;
            
            app.ConnectionStatusLamp.Enable = 0;
            app.ConnectionStatusLamp.Color = [255/255,165/255,0];
            
            if value == 1
                
                app.ConnectionStatusLabel.Text = "Connecting...";
                
                if isprop(app, 'device') && isempty(app.device)
                   try
                       app.device = ble('LeedsMechEngWearable');   % If this doesnt work call 'blelist' in the command window to discover devices
                   catch
                       app.ConnectionStatusLabel.Text = "Couldn't Connect...";
                       app.ConnectionStatusLamp.Color = [1,0,0];
                       app.ConnectionStatusLamp.Enable = 1;
                       app.ConnectButton.Value = 0;
                       app.ConnectButton.Enable = 1;
                       %error('Could not connect...');
                       return
                   end
                
                   app.startImuChar = characteristic(app.device, "f57fd14d-86fc-4d25-a03b-0009f23d4bfd", "49E8");
                   app.imuPrecisionChar = characteristic(app.device, "f57fd14d-86fc-4d25-a03b-0009f23d4bfd", "98B2");
                   app.imuFrequencyChar = characteristic(app.device, "f57fd14d-86fc-4d25-a03b-0009f23d4bfd", "AE89");
                   app.imuStrChar = characteristic(app.device, "f57fd14d-86fc-4d25-a03b-0009f23d4bfd", "785F");
                    
                   app.accelEnabledChar = characteristic(app.device, "f57fd14d-86fc-4d25-a03b-0009f23d4bfd", "1A55");
                   app.gyroEnabledChar = characteristic(app.device, "f57fd14d-86fc-4d25-a03b-0009f23d4bfd", "3901");
                   app.magEnabledChar = characteristic(app.device, "f57fd14d-86fc-4d25-a03b-0009f23d4bfd", "552B");
                   
                   app.gyro1XOffsetChar = characteristic(app.device, "2b53a4b1-33b9-48e4-945c-b7c03f8b7819", "6410");
                   app.gyro1YOffsetChar = characteristic(app.device, "2b53a4b1-33b9-48e4-945c-b7c03f8b7819", "6411");
                   app.gyro1ZOffsetChar = characteristic(app.device, "2b53a4b1-33b9-48e4-945c-b7c03f8b7819", "6412");
                   app.gyro2XOffsetChar = characteristic(app.device, "2b53a4b1-33b9-48e4-945c-b7c03f8b7819", "6413");
                   app.gyro2YOffsetChar = characteristic(app.device, "2b53a4b1-33b9-48e4-945c-b7c03f8b7819", "6414");
                   app.gyro2ZOffsetChar = characteristic(app.device, "2b53a4b1-33b9-48e4-945c-b7c03f8b7819", "6415");
                   app.gyro3XOffsetChar = characteristic(app.device, "2b53a4b1-33b9-48e4-945c-b7c03f8b7819", "6416");
                   app.gyro3YOffsetChar = characteristic(app.device, "2b53a4b1-33b9-48e4-945c-b7c03f8b7819", "6417");
                   app.gyro3ZOffsetChar = characteristic(app.device, "2b53a4b1-33b9-48e4-945c-b7c03f8b7819", "6418");
                    
                   write(app.startImuChar, 0,"withresponse");
                    
                   % This line calls a function 'displayCharacteristicData' each time the
                   %   imuStrChar characteristic is updated with a new value
                   app.imuStrChar.DataAvailableFcn = @ArduinoStr2Table;
                end     
                
                write(app.imuPrecisionChar,app.PrecisionSpinner.Value,"withresponse");
                write(app.imuFrequencyChar,app.FrequencyHzSpinner.Value,"withresponse");
                write(app.accelEnabledChar,accel_enabled,"withresponse");
                write(app.gyroEnabledChar,gyro_enabled,"withresponse");
                write(app.magEnabledChar,mag_enabled,"withresponse");
                
                app.ConnectionStatusLamp.Color = [0,0,1];
                app.ConnectionStatusLabel.Text = "Connected";
                
                app.ConnectButton.Enable = 1;
                app.ConnectButton.Text = "Disconnect";
                
                %Enable all BLE buttons
                app.enableButtons(1);
                
            else
                app.ConnectionStatusLabel.Text = "Disconnecting...";
                
                if isprop(app, 'startImuChar') && ~isempty(app.startImuChar)
                    write(app.startImuChar,0,"withresponse"); %To ensure data recording is turned off
                end
                
                clear device
                app.device = [];
                app.ConnectionStatusLabel.Text = "Disconnected";
                app.ConnectionStatusLamp.Color = [1,0,0];
                
                app.ConnectButton.Enable = 1;
                app.ConnectButton.Text = "Connect";
                
                %Disable all BLE buttons
                app.enableButtons(0);
                app.DataRecordingLamp.Color = [1,0,0];
                
                app.ConnectButton.Enable = 1;
                
                % Set to un-calibrated
                app.sensorsCalibrated = false;

            end
            
            app.ConnectionStatusLamp.Enable = 1;
            
        end

        % Value changed function: AccelerometerButton
        function AccelerometerButtonValueChanged(app, event)
            global accel_enabled
            
            if app.AccelerometerButton.Value
               accel_enabled = 0;
            else
               accel_enabled = 1;
            end
            
            if isprop(app, 'accelEnabledChar') && ~isempty(app.accelEnabledChar)
                write(app.accelEnabledChar,accel_enabled,"withresponse");
            end
        end

        % Value changed function: GyrometerButton
        function GyrometerButtonValueChanged(app, event)
            global gyro_enabled
            
            if app.GyrometerButton.Value
               gyro_enabled = 0;
            else
               gyro_enabled = 1;
            end
            
            if isprop(app, 'device') && ~isempty(app.device) && isprop(app, 'gyroEnabledChar') && ~isempty(app.gyroEnabledChar)
                write(app.gyroEnabledChar,gyro_enabled,"withresponse");
            end
        end

        % Value changed function: MagnetometerButton
        function MagnetometerButtonValueChanged(app, event)
            global mag_enabled
            
            if app.MagnetometerButton.Value
               mag_enabled = 0;
            else
               mag_enabled = 1;
            end
            
            if isprop(app, 'device') && ~isempty(app.device) && isprop(app, 'magEnabledChar') && ~isempty(app.magEnabledChar)
                write(app.magEnabledChar,mag_enabled,"withresponse");
            end
            
        end

        % Button pushed function: OverwriteFileButton, 
        % SaveFileButton
        function SaveFileButtonPushed(app, event)
            app.ErrorLabel.Text = "";
            app.ErrorLabel.FontColor = [1,0,0];
            
            %global data_table
            %saved_table = data_table;
            
            saved_table = app.UITable.Data;
            
            if isempty(saved_table)
                app.ErrorLabel.Text = "There is no recorded data to be saved...";
                return
            end
            
            if ~app.sensorsCalibrated
                app.ErrorLabel.Text = "The sensors were not calibrated before recording...";
                return
            end
            
            if sum(ismember(saved_table.Properties.VariableNames,'movement_type')) == 0
                app.ErrorLabel.Text = "Performed motion was not set for the dataset...";
                return
            end
            
            if sum(ismember(saved_table.Properties.VariableNames,'movement_completeness')) == 0
                app.ErrorLabel.Text = "Movement completeness was not set for the dataset...";
                return
            end
            
            if sum(ismember(saved_table.Properties.VariableNames,'movement_smoothness')) == 0
                app.ErrorLabel.Text = "Movement smoothness was not set for the dataset...";
                return
            end

            folder_name = "Matlab App Data Tables";
            sub_folder_name = string(app.ArmDropDown.Value) + " Arm";
            sub_sub_folder_name = string(app.PerformedMotionDropDown.Value);
            file_name = string(app.FileNameEditField.Value);
            
            % Check to see if folder "Matlab App Data Tables" exists
            if not(exist(folder_name,'dir'))
                mkdir(folder_name)
            end
            
            % Check to see if folder "Matlab App Data Tables/Right Arm" exists
            if not(exist(folder_name+"/"+sub_folder_name, 'dir'))
                mkdir(folder_name+"/"+sub_folder_name)
            end
            
            % Check to see if folder "Matlab App Data Tables/Right Arm/Motion 2" exists
            if not(exist(folder_name+"/"+sub_folder_name+"/"+sub_sub_folder_name, 'dir'))
                mkdir(folder_name+"/"+sub_folder_name+"/"+sub_sub_folder_name)
            end
            
            % Check to see if file allready exists within the folder
            if exist(folder_name+"/"+sub_folder_name+"/"+sub_sub_folder_name+"/"+file_name+".mat","file")
                if event.Source.Text == "Save File"
                    app.ErrorLabel.Text = "File with this name already exists at location " + folder_name+"/"+sub_folder_name+"/"+sub_sub_folder_name;
                    return
                end
            end
            
            % An error catching system. Checks to see if the file could be
            % saved!
            try
                save(folder_name+"/"+sub_folder_name+"/"+sub_sub_folder_name+"/"+file_name,'saved_table'); 
            catch
                app.ErrorLabel.FontColor = [1,0,0];
                app.ErrorLabel.Text = "Could not save the table...";
                return
            end
            app.ErrorLabel.FontColor = [0.39,0.83,0.07]; 
            if event.Source.Text == "Save File"
                app.ErrorLabel.Text = "Successfully Saved!";
            elseif event.Source.Text == "Overwrite File"
                app.ErrorLabel.Text = "Successfully Overwritten!";
            end
            
            pause(1.5);
            app.ErrorLabel.Text = "";

        end

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            ble_list = blelist;
            app.bleTable.Data = ble_list(:,2:4);
            app.bleTable.ColumnName = ble_list.Properties.VariableNames(2:4);
        end

        % Callback function: PerformedMotionDropDown, SetMotionButton
        function SetMotion(app, event)
            global data_table
            movement_column_title = 'movement_type';
            
            output = split(app.PerformedMotionDropDown.Value);
            rank = str2double(output(2));
            data_table{:,movement_column_title} = rank;
            
            app.updateTable();
        end

        % Callback function: CompletenessRankDropDown, SetRankButton
        function SetCompleteness(app, event)
            global data_table
            ranking_column_title = 'movement_completeness';
            
            output = split(app.CompletenessRankDropDown.Value, "/");
            rank = str2double(output(1))/str2double(output(2));
            data_table{:,ranking_column_title} = rank;
            
            app.updateTable();
        end

        % Callback function: SetRankButton_2, SmoothnessRankDropDown
        function SetSmoothness(app, event)
            global data_table
            ranking_column_title = 'movement_smoothness';
            
            output = split(app.SmoothnessRankDropDown.Value, "/");
            rank = str2double(output(1))/str2double(output(2));
            data_table{:,ranking_column_title} = rank;
            
            app.updateTable();
        end

        % Value changing function: FileNameEditField
        function FileNameEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
            if ~isempty(changingValue) && ~isempty(app.UITable.Data)
                app.SaveFileButton.Enable=1;
                app.OverwriteFileButton.Enable=1;
            else
                app.SaveFileButton.Enable=0;
                app.OverwriteFileButton.Enable=0;
            end
        end

        % Value changed function: DataRecordingTimerSlider
        function DataRecordingTimerSliderValueChanged(app, event)
            app.DataRecordingTimerSlider.Value = round(app.DataRecordingTimerSlider.Value);
        end

        % Button pushed function: CalibrateSensorsButton
        function CalibrateSensorsButtonPushed(app, event)
            % Set to uncalibrated
            app.sensorsCalibrated = false;
            
            % Disable buttons
            app.enableButtons(false);
            
            % Clear the table
            app.UITable.Data = table();
            
            % Reset all previous offsets
            write(app.gyro1XOffsetChar,floatToUint8Array(app,0),"withresponse");
            write(app.gyro1YOffsetChar,floatToUint8Array(app,0),"withresponse");
            write(app.gyro1ZOffsetChar,floatToUint8Array(app,0),"withresponse");
            write(app.gyro2XOffsetChar,floatToUint8Array(app,0),"withresponse");
            write(app.gyro2YOffsetChar,floatToUint8Array(app,0),"withresponse");
            write(app.gyro2ZOffsetChar,floatToUint8Array(app,0),"withresponse");
            write(app.gyro3XOffsetChar,floatToUint8Array(app,0),"withresponse");
            write(app.gyro3YOffsetChar,floatToUint8Array(app,0),"withresponse");
            write(app.gyro3ZOffsetChar,floatToUint8Array(app,0),"withresponse");
            
            write(app.startImuChar, 1,"withresponse");
            
            for timer_val = 5:-0.1:0
                app.CalibrationCountdownLabel.Text = compose("%.1f", timer_val);
                pause(0.1);
            end
            
            write(app.startImuChar, 0,"withresponse");
            app.CalibrationCountdownLabel.Text = "";
            
            global data_table
            
            gyro1Xoffset = mean(data_table.gyroX_1);
            gyro1Yoffset = mean(data_table.gyroY_1);
            gyro1Zoffset = mean(data_table.gyroZ_1);
            gyro2Xoffset = mean(data_table.gyroX_2);
            gyro2Yoffset = mean(data_table.gyroY_2);
            gyro2Zoffset = mean(data_table.gyroZ_2);
            gyro3Xoffset = mean(data_table.gyroX_3);
            gyro3Yoffset = mean(data_table.gyroY_3);
            gyro3Zoffset = mean(data_table.gyroZ_3);
            
            write(app.gyro1XOffsetChar,floatToUint8Array(app,gyro1Xoffset),"withresponse");
            write(app.gyro1YOffsetChar,floatToUint8Array(app,gyro1Yoffset),"withresponse");
            write(app.gyro1ZOffsetChar,floatToUint8Array(app,gyro1Zoffset),"withresponse");
            write(app.gyro2XOffsetChar,floatToUint8Array(app,gyro2Xoffset),"withresponse");
            write(app.gyro2YOffsetChar,floatToUint8Array(app,gyro2Yoffset),"withresponse");
            write(app.gyro2ZOffsetChar,floatToUint8Array(app,gyro2Zoffset),"withresponse");
            write(app.gyro3XOffsetChar,floatToUint8Array(app,gyro3Xoffset),"withresponse");
            write(app.gyro3YOffsetChar,floatToUint8Array(app,gyro3Yoffset),"withresponse");
            write(app.gyro3ZOffsetChar,floatToUint8Array(app,gyro3Zoffset),"withresponse");
            
            app.CalibrationIMU1GyroXLabel.Text = "Gyro X Offset = " + gyro1Xoffset;
            app.CalibrationIMU1GyroYLabel.Text = "Gyro Y Offset = " + gyro1Yoffset;
            app.CalibrationIMU1GyroZLabel.Text = "Gyro Z Offset = " + gyro1Zoffset;
            app.CalibrationIMU2GyroXLabel.Text = "Gyro X Offset = " + gyro2Xoffset;
            app.CalibrationIMU2GyroYLabel.Text = "Gyro Y Offset = " + gyro2Yoffset;
            app.CalibrationIMU2GyroZLabel.Text = "Gyro Z Offset = " + gyro2Zoffset;
            app.CalibrationIMU3GyroXLabel.Text = "Gyro X Offset = " + gyro3Xoffset;
            app.CalibrationIMU3GyroYLabel.Text = "Gyro Y Offset = " + gyro3Yoffset;
            app.CalibrationIMU3GyroZLabel.Text = "Gyro Z Offset = " + gyro3Zoffset;
            
            % Set to calibrated
            app.sensorsCalibrated = true;
            
            % Enable buttons
            app.enableButtons(true);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.CalibrateSensorsButton = uibutton(app.CalibrationTab, 'push');
            app.CalibrateSensorsButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateSensorsButtonPushed, true);
            app.CalibrateSensorsButton.Enable = 'off';
            app.CalibrateSensorsButton.Text = 'Calibrate Sensors';
            app.WhichmotionwasperformedLabel_3 = uilabel(app.CalibrationTab);
            app.WhichmotionwasperformedLabel_3.Text = 'Before calibrating the sensors, take the wearable device off and place on a flat steady surface. Do not move/touch the sensors while calibrating.';
            app.CalibrationInfoLabel = uilabel(app.CalibrationTab);
            app.CalibrationInfoLabel.Text = 'IMU 1 (Sternum):';
            app.CalibrationCountdownLabel = uilabel(app.CalibrationTab);
            app.CalibrationCountdownLabel.Text = 'Countdown';
            app.CalibrationIMU1GyroXLabel = uilabel(app.CalibrationTab);
            app.CalibrationIMU1GyroXLabel.Text = {'Gyro X Offset = 0.0'; ''};
            app.CalibrationIMU1GyroYLabel = uilabel(app.CalibrationTab);
            app.CalibrationIMU1GyroYLabel.Text = {'Gyro Y Offset = 0.0'; ''};
            app.CalibrationIMU1GyroZLabel = uilabel(app.CalibrationTab);
            app.CalibrationIMU1GyroZLabel.Text = {'Gyro Z Offset = 0.0'; ''};
            app.CalibrationInfoLabel_6 = uilabel(app.CalibrationTab);
            app.CalibrationInfoLabel_6.Text = 'IMU 2 (Upper Arm):';
            app.CalibrationIMU2GyroXLabel = uilabel(app.CalibrationTab);
            app.CalibrationIMU2GyroXLabel.Text = {'Gyro X Offset = 0.0'; ''};
            app.CalibrationIMU2GyroYLabel = uilabel(app.CalibrationTab);
            app.CalibrationIMU2GyroYLabel.Text = {'Gyro Y Offset = 0.0'; ''};
            app.CalibrationIMU2GyroZLabel = uilabel(app.CalibrationTab);
            app.CalibrationIMU2GyroZLabel.Text = {'Gyro Z Offset = 0.0'; ''};
            app.CalibrationInfoLabel_10 = uilabel(app.CalibrationTab);
            app.CalibrationInfoLabel_10.Text = 'IMU 3 (Lower Arm):';
            app.CalibrationIMU3GyroXLabel = uilabel(app.CalibrationTab);
            app.CalibrationIMU3GyroXLabel.Text = {'Gyro X Offset = 0.0'; ''};
            app.CalibrationIMU3GyroYLabel = uilabel(app.CalibrationTab);
            app.CalibrationIMU3GyroYLabel.Text = {'Gyro Y Offset = 0.0'; ''};
            app.CalibrationIMU3GyroZLabel = uilabel(app.CalibrationTab);
            app.CalibrationIMU3GyroZLabel.Text = {'Gyro Z Offset = 0.0'; ''};
            app.BLETab = uitab(app.TabGroup);
            app.BLETab.Title = 'BLE';
            app.bleTable = uitable(app.BLETab);
            app.bleTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.bleTable.RowName = {};
            app.ScanButton = uibutton(app.BLETab, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.ReadingTab = uitab(app.TabGroup);
            app.ReadingTab.Title = 'Reading';
            app.PrecisionSpinnerLabel = uilabel(app.ReadingTab);
            app.PrecisionSpinnerLabel.Enable = 'off';
            app.PrecisionSpinnerLabel.Text = 'Precision';
            app.PrecisionSpinner = uispinner(app.ReadingTab);
            app.PrecisionSpinner.Limits = [0 6];
            app.PrecisionSpinner.ValueChangedFcn = createCallbackFcn(app, @PrecisionSpinnerValueChanged, true);
            app.PrecisionSpinner.Enable = 'off';
            app.PrecisionSpinner.Value = 3;
            app.FrequencyHzSpinnerLabel = uilabel(app.ReadingTab);
            app.FrequencyHzSpinnerLabel.Enable = 'off';
            app.FrequencyHzSpinnerLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzSpinner = uispinner(app.ReadingTab);
            app.FrequencyHzSpinner.Limits = [1 60];
            app.FrequencyHzSpinner.ValueChangedFcn = createCallbackFcn(app, @FrequencyHzSpinnerValueChanged, true);
            app.FrequencyHzSpinner.Enable = 'off';
            app.FrequencyHzSpinner.Value = 20;
            app.SensorsTab = uitab(app.TabGroup);
            app.SensorsTab.Title = 'Sensors';
            app.MagnetometerButton = uibutton(app.SensorsTab, 'state');
            app.MagnetometerButton.ValueChangedFcn = createCallbackFcn(app, @MagnetometerButtonValueChanged, true);
            app.MagnetometerButton.Enable = 'off';
            app.MagnetometerButton.Text = 'Magnetometer';
            app.GyrometerButton = uibutton(app.SensorsTab, 'state');
            app.GyrometerButton.ValueChangedFcn = createCallbackFcn(app, @GyrometerButtonValueChanged, true);
            app.GyrometerButton.Enable = 'off';
            app.GyrometerButton.Text = 'Gyrometer';
            app.AccelerometerButton = uibutton(app.SensorsTab, 'state');
            app.AccelerometerButton.ValueChangedFcn = createCallbackFcn(app, @AccelerometerButtonValueChanged, true);
            app.AccelerometerButton.Enable = 'off';
            app.AccelerometerButton.Text = 'Accelerometer';
            app.ConnectionStatusLabel = uilabel(app.LeftPanel);
            app.ConnectionStatusLabel.Text = 'ConnectionStatus';
            app.ConnectionStatusLamp = uilamp(app.LeftPanel);
            app.SettingsLabel = uilabel(app.LeftPanel);
            app.SettingsLabel.Text = 'Settings';
            app.DataRecordingSwitchLabel = uilabel(app.LeftPanel);
            app.DataRecordingSwitchLabel.Enable = 'off';
            app.DataRecordingSwitchLabel.Text = 'Data Recording';
            app.DataRecordingSwitch = uiswitch(app.LeftPanel, 'slider');
            app.DataRecordingSwitch.ItemsData = [0 1];
            app.DataRecordingSwitch.ValueChangedFcn = createCallbackFcn(app, @DataRecordingSwitchValueChanged, true);
            app.DataRecordingSwitch.Enable = 'off';
            app.DataRecordingSwitch.Value = 0;
            app.ConnectButton = uibutton(app.LeftPanel, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnecttoDeviceButtonValueChanged, true);
            app.ConnectButton.Text = 'Connect';
            app.DataRecordingLamp = uilamp(app.LeftPanel);
            app.DataRecordingLamp.Enable = 'off';
            app.CountdownLabel = uilabel(app.LeftPanel);
            app.CountdownLabel.Text = 'Countdown';
            app.DataRecordingTimerSecondsLabel = uilabel(app.LeftPanel);
            app.DataRecordingTimerSecondsLabel.Enable = 'off';
            app.DataRecordingTimerSecondsLabel.Text = 'Data Recording Timer (Seconds)';
            app.DataRecordingTimerSlider = uislider(app.LeftPanel);
            app.DataRecordingTimerSlider.Limits = [0 10];
            app.DataRecordingTimerSlider.MajorTicks = [0 2 4 6 8 10];
            app.DataRecordingTimerSlider.MajorTickLabels = {'Off', '2', '4', '6', '8', '10'};
            app.DataRecordingTimerSlider.ValueChangedFcn = createCallbackFcn(app, @DataRecordingTimerSliderValueChanged, true);
            app.DataRecordingTimerSlider.MinorTicks = [1 3 5 7 9];
            app.DataRecordingTimerSlider.Enable = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.FileNameEditFieldLabel = uilabel(app.RightPanel);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.RightPanel, 'text');
            app.FileNameEditField.ValueChangingFcn = createCallbackFcn(app, @FileNameEditFieldValueChanging, true);
            app.SaveFileButton = uibutton(app.RightPanel, 'push');
            app.SaveFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFileButtonPushed, true);
            app.SaveFileButton.Enable = 'off';
            app.SaveFileButton.Text = 'Save File';
            app.NumberofRowsLabel = uilabel(app.RightPanel);
            app.NumberofRowsLabel.Text = 'Number of Rows = N/A';
            app.NumberofColumnsLabel = uilabel(app.RightPanel);
            app.NumberofColumnsLabel.Text = 'Number of Columns = N/A';
            app.CompletenessRankDropDownLabel = uilabel(app.RightPanel);
            app.CompletenessRankDropDownLabel.Text = 'Completeness Rank';
            app.CompletenessRankDropDown = uidropdown(app.RightPanel);
            app.CompletenessRankDropDown.Items = {'1/5', '2/5', '3/5', '4/5', '5/5'};
            app.CompletenessRankDropDown.ValueChangedFcn = createCallbackFcn(app, @SetCompleteness, true);
            app.CompletenessRankDropDown.Value = '1/5';
            app.ErrorLabel = uilabel(app.RightPanel);
            app.SetRankButton = uibutton(app.RightPanel, 'push');
            app.SetRankButton.ButtonPushedFcn = createCallbackFcn(app, @SetCompleteness, true);
            app.SetRankButton.Text = 'Set Rank';
            app.PerformedMotionDropDownLabel = uilabel(app.RightPanel);
            app.PerformedMotionDropDownLabel.Text = 'Performed Motion';
            app.PerformedMotionDropDown = uidropdown(app.RightPanel);
            app.PerformedMotionDropDown.Items = {'Motion 1', 'Motion 2', 'Motion 3', 'Motion 4'};
            app.PerformedMotionDropDown.ValueChangedFcn = createCallbackFcn(app, @SetMotion, true);
            app.PerformedMotionDropDown.Value = 'Motion 1';
            app.SetMotionButton = uibutton(app.RightPanel, 'push');
            app.SetMotionButton.ButtonPushedFcn = createCallbackFcn(app, @SetMotion, true);
            app.SetMotionButton.Text = 'Set Motion';
            app.OverwriteFileButton = uibutton(app.RightPanel, 'push');
            app.OverwriteFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFileButtonPushed, true);
            app.OverwriteFileButton.Enable = 'off';
            app.OverwriteFileButton.Text = 'Overwrite File';
            app.ArmDropDownLabel = uilabel(app.RightPanel);
            app.ArmDropDownLabel.Text = 'Arm';
            app.ArmDropDown = uidropdown(app.RightPanel);
            app.ArmDropDown.Items = {'Left', 'Right'};
            app.ArmDropDown.Value = 'Left';
            app.SmoothnessRankDropDownLabel = uilabel(app.RightPanel);
            app.SmoothnessRankDropDownLabel.Text = 'Smoothness Rank';
            app.SmoothnessRankDropDown = uidropdown(app.RightPanel);
            app.SmoothnessRankDropDown.Items = {'1/5', '2/5', '3/5', '4/5', '5/5'};
            app.SmoothnessRankDropDown.ValueChangedFcn = createCallbackFcn(app, @SetSmoothness, true);
            app.SmoothnessRankDropDown.Value = '1/5';
            app.SetRankButton_2 = uibutton(app.RightPanel, 'push');
            app.SetRankButton_2.ButtonPushedFcn = createCallbackFcn(app, @SetSmoothness, true);
            app.SetRankButton_2.Text = 'Set Rank';
            app.HowaccuratlywasthemotionperformedLabel = uilabel(app.RightPanel);
            app.HowaccuratlywasthemotionperformedLabel.Text = 'How accuratly was the motion performed?';
            app.HowsmoothwasthemotionperformedLabel = uilabel(app.RightPanel);
            app.HowsmoothwasthemotionperformedLabel.Text = 'How smooth was the motion performed?';
            app.WhichmotionwasperformedLabel = uilabel(app.RightPanel);
            app.WhichmotionwasperformedLabel.Text = 'Which motion was performed?';
            app.WhichmotionwasperformedLabel_2 = uilabel(app.RightPanel);
            app.WhichmotionwasperformedLabel_2.Text = 'Which arm are the sensors connected to? (Should be the same as the one performing the motion)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImuBleDataCollectionApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end